classdef DataAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        fileMenu                        matlab.ui.container.Menu
        ImportfileIMenu                 matlab.ui.container.Menu
        mapMenu                         matlab.ui.container.Menu
        YizhuangMapMenu                 matlab.ui.container.Menu
        ChongqingMapMenu                matlab.ui.container.Menu
        SelectLaneRangeMenu             matlab.ui.container.Menu
        ClearMapMenu                    matlab.ui.container.Menu
        measureMenu                     matlab.ui.container.Menu
        measurelineMenu                 matlab.ui.container.Menu
        clearmeasureMenu                matlab.ui.container.Menu
        toolMenu                        matlab.ui.container.Menu
        deleteLabelsMenu                matlab.ui.container.Menu
        singleLablesMenu                matlab.ui.container.Menu
        linksetMenu                     matlab.ui.container.Menu
        linklistMenu                    matlab.ui.container.Menu
        lanelistMenu                    matlab.ui.container.Menu
        curpathMenu                     matlab.ui.container.Menu
        tagtpathMenu                    matlab.ui.container.Menu
        trajectoryMenu                  matlab.ui.container.Menu
        envirvehinfoMenu                matlab.ui.container.Menu
        printtrackMenu                  matlab.ui.container.Menu
        avomainro_envvehinfoMenu        matlab.ui.container.Menu
        oncoming_envvehinfoMenu         matlab.ui.container.Menu
        envirpedinfoMenu                matlab.ui.container.Menu
        replayerMenu                    matlab.ui.container.Menu
        startwiththefirstnumberMenu     matlab.ui.container.Menu
        StartwiththecurrentcursorMenu   matlab.ui.container.Menu
        stopreplayMenu                  matlab.ui.container.Menu
        viewMenu                        matlab.ui.container.Menu
        opencarviewMenu                 matlab.ui.container.Menu
        closecarviewMenu                matlab.ui.container.Menu
        basemapMenu                     matlab.ui.container.Menu
        streetslightMenu                matlab.ui.container.Menu
        streetsdarkMenu                 matlab.ui.container.Menu
        streetsMenu                     matlab.ui.container.Menu
        satelliteMenu                   matlab.ui.container.Menu
        topographicMenu                 matlab.ui.container.Menu
        landcoverMenu                   matlab.ui.container.Menu
        colorterrainMenu                matlab.ui.container.Menu
        grayterrainMenu                 matlab.ui.container.Menu
        bluegreenMenu                   matlab.ui.container.Menu
        graylandMenu                    matlab.ui.container.Menu
        darkwaterMenu                   matlab.ui.container.Menu
        noneMenu                        matlab.ui.container.Menu
        tableMenu                       matlab.ui.container.Menu
        scrollMenu                      matlab.ui.container.Menu
        addtoselectdataMenu             matlab.ui.container.Menu
        removefromselectdataMenu        matlab.ui.container.Menu
        clearselectdataMenu             matlab.ui.container.Menu
        TabGroup2                       matlab.ui.container.TabGroup
        analysisTab                     matlab.ui.container.Tab
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MapLabel                        matlab.ui.control.Label
        chongqingButton                 matlab.ui.control.RadioButton
        eTownButton                     matlab.ui.control.RadioButton
        TabGroup3                       matlab.ui.container.TabGroup
        eTownTab                        matlab.ui.container.Tab
        Tree                            matlab.ui.container.CheckBoxTree
        lanelinepartNode                matlab.ui.container.TreeNode
        linklinepartNode                matlab.ui.container.TreeNode
        lanelineNode                    matlab.ui.container.TreeNode
        linklineNode                    matlab.ui.container.TreeNode
        eTownAllTab                     matlab.ui.container.Tab
        Tree2                           matlab.ui.container.CheckBoxTree
        yzsfq_hd_laneline_v22_84Node    matlab.ui.container.TreeNode
        lanesNode                       matlab.ui.container.TreeNode
        yzsfq_hd_linkline_v22_84Node    matlab.ui.container.TreeNode
        yzsfq_hd_lane_nodepoint_v22_84Node  matlab.ui.container.TreeNode
        yzsfq_hd_link_nodepoint_v22_84Node  matlab.ui.container.TreeNode
        yzsfq_hd_stop_line_v22_84Node   matlab.ui.container.TreeNode
        yzsfq_hd_crossinfo_v22_84Node   matlab.ui.container.TreeNode
        yzsfq_hd_intersection_polygon_v22_84Node  matlab.ui.container.TreeNode
        yzsfq_hd_crosswalk_v22_84Node   matlab.ui.container.TreeNode
        yzsfq_hd_lane_markingline_v22_84Node  matlab.ui.container.TreeNode
        yzsfq_hd_boundary_v22_84Node    matlab.ui.container.TreeNode
        QingTab                         matlab.ui.container.Tab
        Tree2_2                         matlab.ui.container.CheckBoxTree
        yzsfq_hd_laneline_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_linkline_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_lane_nodepoint_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_link_nodepoint_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_stop_line_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_crossinfo_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_intersection_polygon_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_crosswalk_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_lane_markingline_v22_84Node_2  matlab.ui.container.TreeNode
        yzsfq_hd_boundary_v22_84Node_2  matlab.ui.container.TreeNode
        TabGroup                        matlab.ui.container.TabGroup
        ViewTab                         matlab.ui.container.Tab
        ToolTab                         matlab.ui.container.Tab
        envobjectfromvehButton          matlab.ui.control.Button
        envirpedinfoButton              matlab.ui.control.Button
        oncoming_envvehinfoButton       matlab.ui.control.Button
        avomainro_envvehinfoButton      matlab.ui.control.Button
        printtrackButton                matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        envirvehinfoButton              matlab.ui.control.Button
        trajectoryButton                matlab.ui.control.Button
        tagtpathButton                  matlab.ui.control.Button
        curpathButton                   matlab.ui.control.Button
        clearallprintButton             matlab.ui.control.Button
        TextButton                      matlab.ui.control.Button
        lanelistButton                  matlab.ui.control.Button
        linklistButton                  matlab.ui.control.Button
        linksetButton                   matlab.ui.control.Button
        FileDropTab                     matlab.ui.container.Tab
        LoadButton                      matlab.ui.control.Button
        DropsomefilesortextcontenthereLabel  matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        TextAreaFilePath                matlab.ui.control.TextArea
        DataPanel                       matlab.ui.container.Panel
        RouteLabel                      matlab.ui.control.Label
        LightLabel                      matlab.ui.control.Label
        TextAreaRoute                   matlab.ui.control.TextArea
        TextAreaLight                   matlab.ui.control.TextArea
        TextAreaLine                    matlab.ui.control.TextArea
        StatesLabel                     matlab.ui.control.Label
        TextAreaStates                  matlab.ui.control.TextArea
        DecisionLabel                   matlab.ui.control.Label
        TextAreaDecision                matlab.ui.control.TextArea
        SceneactivationLabel            matlab.ui.control.Label
        VehicleLabel                    matlab.ui.control.Label
        RightFrontLabel                 matlab.ui.control.Label
        LeftBehindLabel                 matlab.ui.control.Label
        RightBehindLabel                matlab.ui.control.Label
        LeftFrontLabel                  matlab.ui.control.Label
        CIPVLabel                       matlab.ui.control.Label
        TextAreaScene                   matlab.ui.control.TextArea
        TextAreaRight2                  matlab.ui.control.TextArea
        TextAreaVeh                     matlab.ui.control.TextArea
        TextArealeft2                   matlab.ui.control.TextArea
        TextAreaRight1                  matlab.ui.control.TextArea
        TextAreaCipv                    matlab.ui.control.TextArea
        TextAreaLeft1                   matlab.ui.control.TextArea
        pathLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        rawdataTab                      matlab.ui.container.Tab
        FindButton                      matlab.ui.control.Button
        DropDown_2                      matlab.ui.control.DropDown
        TextAreadata                    matlab.ui.control.TextArea
        Textvariablename                matlab.ui.control.TextArea
        UITable                         matlab.ui.control.Table
        selectdataTab                   matlab.ui.container.Tab
        TextAreadata_2                  matlab.ui.control.TextArea
        Textvariablename_2              matlab.ui.control.TextArea
        UITable_2                       matlab.ui.control.Table
        rundataTab                      matlab.ui.container.Tab
        UITable_4                       matlab.ui.control.Table
        indexLabel                      matlab.ui.control.Label
        indexTextArea                   matlab.ui.control.TextArea
        TextAreadata_3                  matlab.ui.control.TextArea
        Textvariablename_3              matlab.ui.control.TextArea
        UITable_3                       matlab.ui.control.Table
        ContextMenu                     matlab.ui.container.ContextMenu
        scrollMenu_2                    matlab.ui.container.Menu
        addtoselectdataMenu_2           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        removefromselectdataMenu_2      matlab.ui.container.Menu
        clearselectdataMenu_2           matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
        Menu3                           matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu4                    matlab.ui.container.ContextMenu
        scrollMenu_3                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        da % Description
        curtUIAxesDataIndex
        dataIndexLastOfTable
        centerline
        hVeh
        hVehCIPV
        hVehLF
        hVehRF
        hVehLR
        hVehRR
        hVehfail
        hlanefail
        lanefailstore
        gx
        gx_h
        da_gx_h
        vehLocation
        body
        FRwheel
        FLwheel
        RRwheel
        RLwheel
        viewswich
        selectingcolumn_table1
        owndatacolumn_table2
        selectingcolumn_table2
        trajectorycontinue
        envvehfromavomain_swich
        envvehfromoncoming_swich
        envpedfromavo_swich
        carLocationMemory_cell
    end    
    methods (Access = private)        
        function uioutput(app,~,~)
                %表格中高亮
                s = uistyle('BackgroundColor',[1 0.6 0.6]);
                w = uistyle('BackgroundColor',[1 1 1]);
                row = app.da.dataIndex;
                addStyle(app.UITable,s,"row",row);
                rowpre = app.dataIndexLastOfTable;
                if rowpre~=0
                    addStyle(app.UITable,w,"row",rowpre);
                end
                app.dataIndexLastOfTable = app.da.dataIndex;
                %画车辆位置在图2
                if app.viewswich
                    if isempty(app.carLocationMemory_cell{app.da.dataIndex,1})
                        lon = app.da.T.GNSS_LONG(app.da.dataIndex);
                        lat = app.da.T.GNSS_LAT(app.da.dataIndex);
                        yaw = app.da.T.GNSS_HEAD(app.da.dataIndex);
                        [FRwheelc,FLwheelc,RRwheelc,RLwheelc,bodyc,~,~] = carlinefromgeo(lon,lat,yaw);
                        app.carLocationMemory_cell{app.da.dataIndex,1} = lon;
                        app.carLocationMemory_cell{app.da.dataIndex,2} = lat;
%                         app.carLocationMemory_cell{app.da.dataIndex,3} = yaw;
                        app.carLocationMemory_cell{app.da.dataIndex,4} = double(FRwheelc);
                        app.carLocationMemory_cell{app.da.dataIndex,5} = double(FLwheelc);
                        app.carLocationMemory_cell{app.da.dataIndex,6} = double(RRwheelc);
                        app.carLocationMemory_cell{app.da.dataIndex,7} = double(RLwheelc);
                        app.carLocationMemory_cell{app.da.dataIndex,8} = double(bodyc);

                        app.FRwheel.LatitudeData = double(FRwheelc(1,:));
                        app.FRwheel.LongitudeData = double(FRwheelc(2,:));
                        app.FLwheel.LatitudeData = double(FLwheelc(1,:));
                        app.FLwheel.LongitudeData = double(FLwheelc(2,:));
                        app.RRwheel.LatitudeData = double(RRwheelc(1,:));
                        app.RRwheel.LongitudeData = double(RRwheelc(2,:));
                        app.RLwheel.LatitudeData = double(RLwheelc(1,:));
                        app.RLwheel.LongitudeData = double(RLwheelc(2,:));
                        app.body.LatitudeData = double(bodyc(1,:));
                        app.body.LongitudeData = double(bodyc(2,:));
                    else
                        lon= app.carLocationMemory_cell{app.da.dataIndex,1};
                        lat = app.carLocationMemory_cell{app.da.dataIndex,2};
                        FRwheelc = app.carLocationMemory_cell{app.da.dataIndex,4};
                        FLwheelc = app.carLocationMemory_cell{app.da.dataIndex,5};
                        RRwheelc = app.carLocationMemory_cell{app.da.dataIndex,6};
                        RLwheelc = app.carLocationMemory_cell{app.da.dataIndex,7};
                        bodyc = app.carLocationMemory_cell{app.da.dataIndex,8};

                        app.FRwheel.LatitudeData = FRwheelc(1,:);
                        app.FRwheel.LongitudeData = FRwheelc(2,:);
                        app.FLwheel.LatitudeData = FLwheelc(1,:);
                        app.FLwheel.LongitudeData = FLwheelc(2,:);
                        app.RRwheel.LatitudeData = RRwheelc(1,:);
                        app.RRwheel.LongitudeData = RRwheelc(2,:);
                        app.RLwheel.LatitudeData = RLwheelc(1,:);
                        app.RLwheel.LongitudeData = RLwheelc(2,:);
                        app.body.LatitudeData = bodyc(1,:);
                        app.body.LongitudeData = bodyc(2,:);
                    end

                    latlimit = 0.5*abs(app.gx.LatitudeLimits(2)-app.gx.LatitudeLimits(1));
                    lonlimit = 0.5*abs(app.gx.LongitudeLimits(2)-app.gx.LongitudeLimits(1));
                    %                   geolimits(app.gx,[lat-0.00005 lat+0.00005],[lon-0.0005 lon+0.0005])
                    geolimits(app.gx,[lat-latlimit lat+latlimit],[lon-lonlimit lon+lonlimit]);                   
%                     app.gx.TickLabelFormat = '-dd';
                end
                D=app.da.D;
                app.TextAreaLeft1.Value = [
                    D('leftLaneFrontDis');
                    D('leftLaneFrontVel');
                    D('leftLaneFrontLen');
                    ];
                app.TextAreaRight1.Value = [
                    D('rightLaneFrontDis');
                    D('rightLaneFrontVel');
                    D('rightLaneFrontLen');
                    ];
                app.TextArealeft2.Value = [
                    D('leftLaneBehindDis');
                    D('leftLaneBehindVel');
                    D('leftLaneBehindLen');
                    ];
                app.TextAreaRight2.Value = [
                    D('rightLaneBehindDis');
                    D('rightLaneBehindVel');
                    D('rightLaneBehindLen');
                    ];
%                 cipvInfo = strsplit(D('cipvInfo'),{' ',','});
                cipvInfo = strsplit(D('cipvInfo'),{' ',',','{','}'});
                if length(cipvInfo) == 3 %说明cipvinfo为空
                    cipvInfo = [cipvInfo,{''},{''},{''},{''},{''},{''},{''},{''},{''},{''}];
                end
                app.TextAreaCipv.Value = [
                    string(['cipvLonDis= ',cipvInfo{1,3},' , ',cipvInfo{1,8}]);
                    string(['cipvSpeed = ',cipvInfo{1,4},' , ',cipvInfo{1,9}]);
                    string(['cipvLenght = ',cipvInfo{1,5},' , ',cipvInfo{1,10}]);
                    string(['cipvWidth = ',cipvInfo{1,6},' , ',cipvInfo{1,11}]);
                    string(['cipvLatDis = ',cipvInfo{1,7},' , ',cipvInfo{1,12}]);
                    ];
                trajIn = strsplit(D('trajInput'),{', ''[',']'', '});
                if length(trajIn) ==1
                    trajinfo = [{''},{''},{''},{''},{''},{''},{''},{''},{''}];
                else
                    trajIn1 = strsplit(trajIn{1,1},{' ', ',','['});
                    trajIn3 = strsplit(trajIn{1,3},{' ', ',',']'});
                    trajinfo = [trajIn1(1,3:end),trajIn(1,2),trajIn3];

                end
                app.TextAreaVeh.Value = [
                    D('vehicleId') ;
                    string(['currentlane = ',trajinfo{1,4}]) ;
                    string(['targetlane = ',trajinfo{1,6}]) ;
                    D('VEH_SPD') ;
                    D('GNSS_HEAD') ;
                    D('decisionLaneChange') ;
                    string(['pos_s = ',trajinfo{1,1}]) ;
                    string(['pos_l = ',trajinfo{1,2}]) ;
                    ];
                app.TextAreaDecision.Value = [
                    
                    D('laneChgExp') ;
                    D('targetSpd') ;
                    D('stopDis') ;
                    D('stopTips') ;
                    D('startTips') ;
                    D('emgBrak') ;
                    ];
                app.TextAreaScene.Value = [
                    D('LaneChangeActive') ;
                    D('PedestrianActive') ;
                    D('TrafficLightActive') ;
                    D('VehicleCrossingActive') ;
                    D('VehicleOncomingActive') ;
                    D('TurnAroundActive') ;
                    D('glosaActive_so') ;
                    ];
                app.TextAreaStates.Value = [
                    D('sceneID') ;
                    D('flagFailWarn') ;
                    D('states') ;
                    D('algo_receive') ;
                    D('algo_send') ;
                    D('kafka_time_stamp') ;

                    ];
                if sum(strcmp(app.da.T.Properties.VariableNames,'avoFailVehInfo'))>0%判断是否存在
                    avoFailVeh_cell = strsplit(D('avoFailVehInfo'),' = ');
                    a =strsplit(avoFailVeh_cell{1,2},{'{','}'});
                    b = strsplit(a{1,2},{'lanesWithFail=',', failLaneindex=',', failLaneFrontDis=',', failLaneFrontVel=',', failLaneFrontLen='});
                    lanesWithFail = jsondecode(b{1,2});
                    failLaneindex = jsondecode(b{1,3});
                    failLaneFrontDis = jsondecode(b{1,4});
                else
                    avoFailVeh_cell = {{},{strcat("avoFailVehInfo"," = ","Not found")}};
                    lanesWithFail = zeros(5,1);
                    failLaneindex = zeros(5,1);
                    failLaneFrontDis = zeros(5,1);
                end

                app.TextAreaLine.Value = [
                    string(['dataIndex = ',num2str(app.da.dataIndex)]) ;
                    D('kafka_time_date') ;
                    D('sHvLink') ;
                    D('s_start') ;
                    D('linkList') ;
                    D('trace') ;
                    D('laneList') ;
                    string(avoFailVeh_cell{1,2}) ;
                    D('curtPath2Points') ;
                    D('tagtPath2Points') ;
                    "****************************************************************************************************************"
                    "****************************--------------------AvoMainRoVehInfo--------------------****************************"
                    D('AvoMainRoVehInfo_targetLaneBehindDisAvoidVehicle') ;
                    D('AvoMainRoVehInfo_targetLaneBehindVelAvoidVehicle') ;
                    D('AvoMainRoVehInfo_targetLaneBehindLenAvoidVehicle') ;
                    D('AvoMainRoVehInfo_targetLaneFrontDisAvoidVehicle') ;
                    D('AvoMainRoVehInfo_targetLaneFrontVelAvoidVehicle') ;
                    D('AvoMainRoVehInfo_targetLaneFrontLenAvoidVehicle') ;
                    D('AvoMainRoVehInfo_d_veh2converge') ;
                    D('AvoMainRoVehInfo_d_veh2stopline') ;
                    D('dec_fol_AvoidVehicle') ;
                    D('dec_bre_AvoidVehicle') ;
                    D('wait_AvoidVehicle') ;
                    "****************************************************************************************************************"
                    "****************************-------------------AvoOncomingVehInfo-------------------***************************"
                    D('AvoOncomingVehInfo_d_veh2waitingArea') ;
                    D('AvoOncomingVehInfo_s_veh') ;
                    D('AvoOncomingVehInfo_v_veh') ;
                    D('AvoOncomingVehInfo_l_veh') ;
                    D('AvoOncomingVehInfo_d_veh2conflict') ;
                    D('AvoOncomingVehInfo_s_vehapostrophe') ;
                    D('AvoOncomingVehInfo_l_vehapostrophe') ;
                    D('dec_avoidOncomingVehicle') ;
                    D('wait_avoidOncomingVehicle') ;
                    "****************************************************************************************************************"
                    "*********************************-------------------AvoPedInfo-------------------********************************"
                    D('d_veh2cross') ;
                    D('w_cross') ;
                    D('s_ped') ;
                    D('v_ped') ;
                    D('l_ped') ;
                    D('psi_ped') ;
                    D('dec_ped') ;
                    D('wait_ped') ;
                    ];
                app.TextAreaLight.Value = [
                    D('color') ;
                    D('time_left') ;
                    D('d_veh2stopline') ;
                    D('phase') ;
                    ];
                app.TextAreaRoute.Value = [
                    string(['v_max = ',trajinfo{1,7}]) ;
                    string(['d_veh2int = ',trajinfo{1,8}]) ;
                    string(['widthOfLanes = ',trajinfo{1,5}]) ;
                    D('d_veh2goal') ;
                    D('goalLaneIndex') ;
                    ];

                %画图-------------------------------------------------------------------
                drawLaneFlag = 0;
                if app.curtUIAxesDataIndex ==0%第一次画图
                    drawLaneFlag = 1;
                else
                    linklistdouble = tablecell2double(app.da.T.linkList(app.da.dataIndex));
                    linklistdoublepre = tablecell2double(app.da.T.linkList(app.curtUIAxesDataIndex));
                    if ~isequal(linklistdoublepre,linklistdouble)%当前的linklist与画图的linklisat不同
                        drawLaneFlag = 1;
                    end
                end
                if drawLaneFlag
                    hold(app.UIAxes,'off');
                    for index = app.da.dataIndex:-1:1 %计算linklist变化的最近的索引
                        if index-1 >= 1
                            linklistdouble = tablecell2double(app.da.T.linkList(index));
                            linklistdoublepre = tablecell2double(app.da.T.linkList(index-1));
                            if ~isequal(linklistdoublepre,linklistdouble)
                                break
                            end
                        else
                            linklistdouble = tablecell2double(app.da.T.linkList(index));
                            break

                        end
                    end
                    trace = str2num(cell2mat(app.da.T.trace(index)));%一列
                    numOfLinklist = length(linklistdouble);
                    if isempty(trace)
                        trace = zeros(numOfLinklist,1);
                        NumOfLanes = zeros(numOfLinklist,1)+1;
                        if numOfLinklist==1
                            s_start(1) = 0;
                            s_start(2) = 100;
                        else
                            s_start = jsondecode(cell2mat(app.da.T.s_start(index)));%一列
                            s_start(end+1) = s_start(end)+50;
                        end
                    else
                        a = app.da.T.trafficInfo_segment(index);%cell

                        char2asc=abs(cell2mat(a));%转asc2
                        char2asc(char2asc==abs(''''))=[];%删除'
                        c=char(char2asc);
                        b = jsondecode(c);

%                         b=jsondecode(cell2mat(a));%cell
                        NumOfLanes=zeros(length(b),1);
                        for k=1:length(b)
                            NumOfLanes(k) = b{k,1}{1,1};
                        end
                        s_start = jsondecode(cell2mat(app.da.T.s_start(index)));%一列
                        s_start(end+1) = s_start(end)+50;
                    end
                    %画车道--------------------------------------------------
                    widthOfLane=3.2;
                    %画车道数量
%                     NumOfLanesPlot = max(max(trace),4);
                    NumOfLanesPlot = max(NumOfLanes);
                    Centerline = zeros(1,NumOfLanesPlot);
                    for i=1:length(trace)
                        for j=1:NumOfLanesPlot
                            Centerline(j)=-((j-1)*widthOfLane+0.5*widthOfLane);%车道中心线
                            %画车道
                            x=s_start(i);
                            y=-j*widthOfLane;
                            w=s_start(i+1);
                            h=widthOfLane-0.05;
                            if  j==trace(i) %绿色矩形目标车道 [100 156 100]
                                recta(j,i) = rectangle(app.UIAxes,'Position',[x y w h],'edgecolor',[255 250 250]/255,'facecolor',[46 139 87]/255,'linewidth',0.1);
                            elseif j<=NumOfLanes(i) %灰色矩形车道
                                recta(j,i) = rectangle(app.UIAxes,'Position',[x y w h],'edgecolor',[255 250 250]/255,'facecolor',[130 130 130]/255,'linewidth',0.1);
                            else %白色矩形
                                recta(j,i) = rectangle(app.UIAxes,'Position',[x y w h],'edgecolor',[255 250 250]/255,'facecolor',[255 255 255]/255,'linewidth',0.1);
                            end
                        end

                    end
                    %标记事件车道
                    if sum(strcmp(app.da.T.Properties.VariableNames,'abnormalEventsSegment'))>0%判断是否存在
                        abnormalEventsSegment_char = cell2mat(app.da.T.abnormalEventsSegment(index));
                    else
                        abnormalEventsSegment_char = [];
                    end
                    if ~isempty(abnormalEventsSegment_char)
                        abnormalEventsSegment_double = jsondecode(abnormalEventsSegment_char);
                        abnormalEventsLane_double = jsondecode(cell2mat(app.da.T.abnormalEventsLane(index)));
                        for eventIndex =1:nnz(abnormalEventsSegment_double)
                            if trace(abnormalEventsSegment_double(eventIndex)) == abnormalEventsLane_double(eventIndex)% 鞍棕色
                                recta(abnormalEventsLane_double(eventIndex),abnormalEventsSegment_double(eventIndex)).FaceColor = [139 69 19]/255 ;
                            else %棕色
                                recta(abnormalEventsLane_double(eventIndex),abnormalEventsSegment_double(eventIndex)).FaceColor = [165 42 42]/255 ;
                            end
                        end
                    end
                    %处理坐标显示范围
                    ylim(app.UIAxes,[-widthOfLane*NumOfLanesPlot,0])
                    xlim(app.UIAxes,[0,s_start(end)])

                    %处理Y轴刻度
                    ytick=sort(Centerline);
                    yticklabel=cell(1,NumOfLanesPlot);
                    for i=1:NumOfLanesPlot
                        yticklabel{1,i}=['lane' num2str(NumOfLanesPlot-i+1)];
                    end
                    %     set(gca,'ytick',[-8 -4.8 -1.6])  %x坐标轴上刻度的数据点位置
                    %     set(gca,'yticklabel',{'3','2','1'}) %x坐标轴上每个刻度处显示的字符
                    set(app.UIAxes,'ytick',ytick)
                    set(app.UIAxes,'yticklabel',yticklabel)
                    %处理X轴刻度
                    xlabel(app.UIAxes,'S(m)')
                    set(app.UIAxes,'xtick',s_start(1:end-1))
                    %缓存变量
                    app.curtUIAxesDataIndex = index;
                    app.centerline = Centerline;
                end
                %% 画主车与环境车
                leftLaneFrontDis = app.da.T.leftLaneFrontDis(app.da.dataIndex);
                rightLaneFrontDis = app.da.T.rightLaneFrontDis(app.da.dataIndex);
                leftLaneBehindDis = app.da.T.leftLaneBehindDis(app.da.dataIndex);
                rightLaneBehindDis = app.da.T.rightLaneBehindDis(app.da.dataIndex);
                cipvDis = str2double(cipvInfo{1,3});
                currentlane = str2double(trajinfo{1,4});
                sHvLink = app.da.T.sHvLink(app.da.dataIndex);

                app.hVeh.XData = sHvLink;
                app.hVeh.YData = app.centerline(min(currentlane,length(app.centerline)));
                uistack(app.hVeh,'top')
                if leftLaneFrontDis ~= 0 && leftLaneFrontDis ~= 200
                    uistack(app.hVehLF,'top')
                    app.hVehLF.Visible = 'on';
                    app.hVehLF.XData = sHvLink+leftLaneFrontDis;
                    app.hVehLF.YData = app.centerline(currentlane-1);
                else
                    app.hVehLF.Visible = 'off';
                end
                if rightLaneFrontDis ~= 0 && rightLaneFrontDis ~= 200
                    uistack(app.hVehRF,'top')
                    app.hVehRF.Visible = 'on';
                    app.hVehRF.XData = sHvLink+rightLaneFrontDis;
                    app.hVehRF.YData = app.centerline(currentlane+1);
                else
                    app.hVehRF.Visible = 'off';
                    
                end
                if leftLaneBehindDis ~= 0 && leftLaneBehindDis ~= -200
                    uistack(app.hVehLR,'top')
                    app.hVehLR.Visible = 'on';
                    app.hVehLR.XData = sHvLink+leftLaneBehindDis;
                    app.hVehLR.YData = app.centerline(currentlane-1);
                else
                    app.hVehLR.Visible = 'off';
                end
                if rightLaneBehindDis ~= 0 && rightLaneBehindDis ~= -200
                    uistack(app.hVehRR,'top')
                    app.hVehRR.Visible = 'on';
                    app.hVehRR.XData = sHvLink+rightLaneBehindDis;
                    app.hVehRR.YData = app.centerline(currentlane+1);
                else
                    app.hVehRR.Visible = 'off';
                end
                if cipvDis ~= 200
                    uistack(app.hVehCIPV,'top')
                    app.hVehCIPV.Visible = 'on';
                    app.hVehCIPV.XData = sHvLink+cipvDis;
                    app.hVehCIPV.YData = app.centerline(min(currentlane,length(app.centerline)));%防止当前车道大于存储的车道中心线数量
                else
                    app.hVehCIPV.Visible = 'off';
                end
                %故障车显示再坐标区
                for failvehindex = 1:5
                    if failLaneindex(failvehindex)~=0
                    uistack(app.hVehfail(failvehindex),'top')
                    app.hVehfail(failvehindex).Visible = 'on';
                    app.hVehfail(failvehindex).XData = sHvLink+failLaneFrontDis(failvehindex);
                    app.hVehfail(failvehindex).YData = app.centerline(failLaneindex(failvehindex));
                    else
                        app.hVehfail(failvehindex).Visible = 'off';
                    end
                    if lanesWithFail(failvehindex)~=app.lanefailstore(failvehindex)
                        if lanesWithFail(failvehindex)~=0
                            uistack(app.hlanefail(failvehindex),'top')
                            app.hlanefail(failvehindex).Visible = 'on';
                            app.hlanefail(failvehindex).XData =zeros(1,5) + sHvLink+60;
                            y = app.centerline(lanesWithFail(failvehindex));
                            app.hlanefail(failvehindex).YData = [y-0.5*3.2,y-0.25*3.2,y,y+0.25*3.2,y+0.5*3.2];
                        else
                            app.hlanefail(failvehindex).Visible = 'off';
                        end
                        app.lanefailstore(failvehindex)=lanesWithFail(failvehindex);
                    end
                end
                %处理打印操作
                if app.trajectorycontinue == 1
                    app.da.trajectory;
                end
                if app.envvehfromavomain_swich == 1
                    app.da.envvehfromavomain;
                end
                if app.envvehfromoncoming_swich == 1
                    app.da.envvehfromoncoming;
                end
                if app.envpedfromavo_swich == 1
                    app.da.envpedfromavo;
                end
                %% ------------------------------------------------------------------------
%                 disp('app current is cursorChanged')
            function linklistdouble = tablecell2double(c)
                linklistchar = cell2mat(c);
                if isempty(linklistchar)
                    linklistdouble = 0;
                else
                    char2asc2=abs(linklistchar);%转asc2
                    char2asc2(char2asc2==abs(''''))=[];%删除'
                    linklistchar=char(char2asc2);
                    linklistdouble = jsondecode(linklistchar);
                end
            end            
        end

        function uitablecallback(app,src,event)
            rows = event.Selection(:,1);
            columns = event.Selection(:,2);
            Data = src.Data(rows(1),columns(1));           
            var = table2array(Data(1,1));
            if iscell(var)
                str = string(cell2mat(var));
            elseif ~isa(var,"datetime") && isnan(var)
                str ="NaN";
            else
                str = string(var);
            end
            app.Textvariablename.Value = Data.Properties.VariableNames{1,1};
            if strcmp(app.Textvariablename.Value,'algo_receive') || strcmp(app.Textvariablename.Value,'algo_send') || strcmp(app.Textvariablename.Value,'kafka_time_stamp') || strcmp(app.Textvariablename.Value,'timeStampSet')
                t = datetime(str2double(str)/1000, 'ConvertFrom', 'posixtime' ,'TimeZone','local','Format','uuuu-MM-dd''T''HH:mm:ss.SSS');
                app.TextAreadata.Value = string(t);
            else
                app.TextAreadata.Value = str;
            end
            app.selectingcolumn_table1 = unique(columns,'stable');
%             Data.Properties.VariableNames
        end
        function uitable_2callback(app,src,event)
            rows = event.Selection(:,1);
            columns = event.Selection(:,2);
            Data = src.Data(rows(1),columns(1));
            
            var = table2array(Data(1,1));

            if iscell(var)
                str = string(cell2mat(var));
            elseif ~isa(var,"datetime") && isnan(var)
                str ="NaN";
            else
                str = string(var);
            end
            app.Textvariablename_2.Value = Data.Properties.VariableNames{1,1};
            app.TextAreadata_2.Value = str;
            app.selectingcolumn_table2 = unique(columns,'stable');
        end
        function uitable_3callback(app,src,event)
            rows = event.Selection(:,1);
            columns = event.Selection(:,2);
            Data = src.Data(rows(1),columns(1));
            
            var = table2array(Data(1,1));

            if iscell(var)
                str = string(cell2mat(var));
            elseif ~isa(var,"datetime") && isnan(var)
                str ="NaN";
            else
                str = string(var);
            end

            app.Textvariablename_3.Value = Data.Properties.VariableNames{1,1};
            app.TextAreadata_3.Value = str;
            if strcmp(app.Textvariablename_3.Value,'detection') 
                detection_cell = Data.detection(1);
                detection_struct =jsondecode(detection_cell{1,1});
                targets_table = struct2table(detection_struct.targets);
                app.UITable_4.Data = targets_table;
                app.UITable_4.ColumnName = targets_table.Properties.VariableNames;
            end
%             app.selectingcolumn_table2 = unique(columns,'stable');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.trajectorycontinue = 0;
            app.gx = geoaxes(app.ViewTab);
            geobasemap(app.gx,"satellite")
            app.UITable.RowName = 'numbered';
            app.UITable_2.RowName = 'numbered';
            app.UITable_3.RowName = 'numbered';
            app.UITable_4.RowName = 'numbered';
            app.dataIndexLastOfTable = 0;
            app.gx.TickLabelFormat = '-dd';
            app.viewswich = true ;

            hold(app.UIAxes,'on');
            app.hVehLF = plot(app.UIAxes,0,0,'b','Marker',"square");
            app.hVehRF = plot(app.UIAxes,0,0,'b','Marker',"square");
            app.hVehLR = plot(app.UIAxes,0,0,'b','Marker',"square");
            app.hVehRR = plot(app.UIAxes,0,0,'b','Marker',"square");
            app.hVehCIPV = plot(app.UIAxes,0,0,'b','Marker',"square");
            app.hVeh = plot(app.UIAxes,0,0,'r','Marker',"pentagram");
            %故障车显示再坐标区
            h1 = plot(app.UIAxes,0,0,'r','Marker','x','Visible','off');
            h2 = plot(app.UIAxes,0,0,'r','Marker','x','Visible','off');
            h3 = plot(app.UIAxes,0,0,'r','Marker','x','Visible','off');
            h4 = plot(app.UIAxes,0,0,'r','Marker','x','Visible','off');
            h5 = plot(app.UIAxes,0,0,'r','Marker','x','Visible','off');
            app.hVehfail = [h1;h2;h3;h4;h5];
            h11 = plot(app.UIAxes,[0,0,0,0,0],[0,0,0,0,0],'r','Marker','x','Visible','off');
            h22 = plot(app.UIAxes,[0,0,0,0,0],[0,0,0,0,0],'r','Marker','x','Visible','off');
            h33 = plot(app.UIAxes,[0,0,0,0,0],[0,0,0,0,0],'r','Marker','x','Visible','off');
            h44 = plot(app.UIAxes,[0,0,0,0,0],[0,0,0,0,0],'r','Marker','x','Visible','off');
            h55 = plot(app.UIAxes,[0,0,0,0,0],[0,0,0,0,0],'r','Marker','x','Visible','off');
            app.hlanefail = [h11;h22;h33;h44;h55];
            app.lanefailstore = zeros(1,5);
            %设置标签
%             label = uilabel(app.UIFigure,'Text',' H','Position',[100 698 15 15],'FontColor','w','BackgroundColor',"#4F4F4F");
            %设置快捷键
            app.ImportfileIMenu.Accelerator = "I";
%             app.analysisTab.
%            设置文件拖拽回调
             DnD_uifigure(app.ListBox, @(o,dat)set(o,'Items',dat.names));
        end

        % Menu selected function: ImportfileIMenu
        function ImportfileIMenuSelected(app, event)
            close
            filePath = [];
            app.da = DataAnalyse(filePath);
            if isempty(app.da.T)
                return
            end
            app.carLocationMemory_cell = cell(nnz(app.da.T.GNSS_LONG),8);
            app.curtUIAxesDataIndex = 0;
            %进度条
            d = uiprogressdlg(app.UIFigure,'Title','正在地理坐标系画图',...
                'Indeterminate','on');
            drawnow
            app.da.geoshowdata;
            drawnow %为了快点显示坐标图窗
            lb=addlistener(app.da.DataCursorPublisher,'dataIndeChanged',@app.uioutput);
            app.TextAreaFilePath.Value = app.da.path;
            app.TextAreaLine.Value = " Default Map of Yizhuang";

            %表格设置
            app.UITable.Data = app.da.T;
%             fig = uifigure;
%             uit = uitable(fig,'Data',app.da.T);


            app.UITable.ColumnName = app.da.T.Properties.VariableNames;

            app.UITable.SelectionChangedFcn = @app.uitablecallback; %表格回调函数
            app.UITable_2.SelectionChangedFcn = @app.uitable_2callback;

            if ~isempty(app.da.vehData_T)
                app.UITable_3.Data = app.da.vehData_T;
                app.UITable_3.ColumnName = app.da.vehData_T.Properties.VariableNames;
                app.UITable_3.SelectionChangedFcn = @app.uitable_3callback; %表格回调函数
            end
            %小车画图设置

            lon = app.da.T.GNSS_LONG(1);
            lat = app.da.T.GNSS_LAT(1);
            yaw = app.da.T.GNSS_HEAD(1);
%             [lon2,lat2]=startangle2endhigh(lon,lat,yaw,2.5);   
%             app.vehLocation = geoplot(app.gx,lat,lon,'*',double(lat2),double(lon2),'^');


            [FRwheelc,FLwheelc,RRwheelc,RLwheelc,bodyc,~,~] = carlinefromgeo(lon,lat,yaw);

            app.body = geoplot(app.gx,double(bodyc(1,:)),double(bodyc(2,:)),'LineWidth',2.5,'Color',"#0072BD"); %
            hold(app.gx,'on');
            app.FRwheel = geoplot(app.gx,double(FRwheelc(1,:)),double(FRwheelc(2,:)),'LineWidth',3,'Color','k');
            app.FLwheel = geoplot(app.gx,double(FLwheelc(1,:)),double(FLwheelc(2,:)),'LineWidth',3,'Color','k');
            app.RRwheel = geoplot(app.gx,double(RRwheelc(1,:)),double(RRwheelc(2,:)),'LineWidth',3,'Color','k');
            app.RLwheel = geoplot(app.gx,double(RLwheelc(1,:)),double(RLwheelc(2,:)),'LineWidth',3,'Color','k');
            hold(app.gx,'off');
            geolimits(app.gx,[lat-0.0001 lat+0.0001],[lon-0.001 lon+0.001])
            app.gx.TickLabelFormat = '-dd';

            % close the dialog box
            app.DropDown.Items = app.da.T.Properties.VariableNames;
            app.DropDown_2.Items = app.da.T.Properties.VariableNames;
            close(d)


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            close
            filePath = app.ListBox.Value;
            app.da = DataAnalyse(filePath);
            if isempty(app.da.T)
                return
            end
            app.carLocationMemory_cell = cell(nnz(app.da.T.GNSS_LONG),8);
            app.curtUIAxesDataIndex = 0;
            %进度条
            d = uiprogressdlg(app.UIFigure,'Title','正在地理坐标系画图',...
                'Indeterminate','on');
            drawnow
            app.da.geoshowdata;
            drawnow %为了快点显示坐标图窗
            lb=addlistener(app.da.DataCursorPublisher,'dataIndeChanged',@app.uioutput);
            app.TextAreaFilePath.Value = app.da.path;
            app.TextAreaLine.Value = " Default Map of Yizhuang";

            %表格设置
            app.UITable.Data = app.da.T;
%             fig = uifigure;
%             uit = uitable(fig,'Data',app.da.T);


            app.UITable.ColumnName = app.da.T.Properties.VariableNames;

            app.UITable.SelectionChangedFcn = @app.uitablecallback; %表格回调函数
            app.UITable_2.SelectionChangedFcn = @app.uitable_2callback;
            if ~isempty(app.da.vehData_T)
                app.UITable_3.Data = app.da.vehData_T;
                app.UITable_3.ColumnName = app.da.vehData_T.Properties.VariableNames;
                app.UITable_3.SelectionChangedFcn = @app.uitable_3callback; %表格回调函数
            end
            %小车画图设置

            lon = app.da.T.GNSS_LONG(1);
            lat = app.da.T.GNSS_LAT(1);
            yaw = app.da.T.GNSS_HEAD(1);
%             [lon2,lat2]=startangle2endhigh(lon,lat,yaw,2.5);   
%             app.vehLocation = geoplot(app.gx,lat,lon,'*',double(lat2),double(lon2),'^');


            [FRwheelc,FLwheelc,RRwheelc,RLwheelc,bodyc,~,~] = carlinefromgeo(lon,lat,yaw);

            app.body = geoplot(app.gx,double(bodyc(1,:)),double(bodyc(2,:)),'LineWidth',2.5,'Color',"#0072BD"); %
            hold(app.gx,'on');
            app.FRwheel = geoplot(app.gx,double(FRwheelc(1,:)),double(FRwheelc(2,:)),'LineWidth',3,'Color','k');
            app.FLwheel = geoplot(app.gx,double(FLwheelc(1,:)),double(FLwheelc(2,:)),'LineWidth',3,'Color','k');
            app.RRwheel = geoplot(app.gx,double(RRwheelc(1,:)),double(RRwheelc(2,:)),'LineWidth',3,'Color','k');
            app.RLwheel = geoplot(app.gx,double(RLwheelc(1,:)),double(RLwheelc(2,:)),'LineWidth',3,'Color','k');
            hold(app.gx,'off');
            geolimits(app.gx,[lat-0.0001 lat+0.0001],[lon-0.001 lon+0.001])
            app.gx.TickLabelFormat = '-dd';

            % close the dialog box
            app.DropDown.Items = app.da.T.Properties.VariableNames;
            app.DropDown_2.Items = app.da.T.Properties.VariableNames;
            close(d)

        end

        % Button pushed function: TextButton
        function TextButtonPushed(app, event)
%             if ~isempty(app.EditField.Value)
%                 app.da.opentext(app.EditField.Value)
%             end
            app.da.opentext(deblank(app.DropDown.Value));
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            column_index = find(strcmp(app.DropDown_2.Value,app.DropDown_2.Items));
            scroll(app.UITable,'column',column_index);
        end

        % Button pushed function: clearallprintButton
        function clearallprintButtonPushed(app, event)
            app.da.deletealltext;
            app.trajectorycontinue = 0;
            app.envvehfromavomain_swich = 0;
            app.envvehfromoncoming_swich = 0;
            app.envpedfromavo_swich = 0;
        end

        % Button pushed function: linklistButton
        function linklistButtonPushed(app, event)
            app.da.linklist;
        end

        % Button pushed function: lanelistButton
        function lanelistButtonPushed(app, event)
            app.da.lanelist;
        end

        % Button pushed function: linksetButton
        function linksetButtonPushed(app, event)
            app.da.linkset;
        end

        % Button pushed function: curpathButton
        function curpathButtonPushed(app, event)
            app.da.curtpath;
        end

        % Button pushed function: tagtpathButton
        function tagtpathButtonPushed(app, event)
            app.da.tagtPath;
        end

        % Button pushed function: trajectoryButton
        function trajectoryButtonPushed(app, event)
            app.da.trajectory;
        end

        % Button pushed function: envirvehinfoButton
        function envirvehinfoButtonPushed(app, event)
            app.da.envirvehinfo;
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            app.da.deletemaptext;
            for i=1:length(checkedNodes)
                if strcmp(checkedNodes(i).Text,'lanelinepart')
                    app.da.dispLaneOfMap;
                elseif strcmp(checkedNodes(i).Text,'linklinepart')
                    app.da.dispLinkOfMap;
                elseif strcmp(checkedNodes(i).Text,'laneline')
                    app.da.dispAllLaneOfMap;
                elseif strcmp(checkedNodes(i).Text,'linkline')
                    app.da.dispAllLinkOfMap;
                end
            end
        end

        % Menu selected function: YizhuangMapMenu
        function YizhuangMapMenuSelected(app, event)
            app.da.mapchange(0);
            app.TextAreaLine.Value = "Map of Yizhuang";
%             f = msgbox("The operation has been successfully completed","Success");
        end

        % Menu selected function: ChongqingMapMenu
        function ChongqingMapMenuSelected(app, event)
            app.da.mapchange(1)
            app.TextAreaLine.Value = "Map of Chongqing";
%             f = msgbox("The operation has been successfully completed","Success");
        end

        % Menu selected function: measurelineMenu
        function measurelineMenuSelected(app, event)
            app.da.measureline;
        end

        % Menu selected function: clearmeasureMenu
        function clearmeasureMenuSelected(app, event)
            app.da.deletemeasureline
        end

        % Menu selected function: SelectLaneRangeMenu
        function SelectLaneRangeMenuSelected(app, event)
            app.da.dispLaneOfMapManual;
        end

        % Menu selected function: ClearMapMenu
        function ClearMapMenuSelected(app, event)
            app.da.deletemaptext;
        end

        % Menu selected function: linksetMenu
        function linksetMenuSelected(app, event)
            app.da.linkset;
        end

        % Menu selected function: linklistMenu
        function linklistMenuSelected(app, event)
            app.da.linklist;
        end

        % Menu selected function: lanelistMenu
        function lanelistMenuSelected(app, event)
            app.da.lanelist;
        end

        % Menu selected function: curpathMenu
        function curpathMenuSelected(app, event)
            app.da.curtpath;
        end

        % Menu selected function: tagtpathMenu
        function tagtpathMenuSelected(app, event)
            app.da.tagtPath;
        end

        % Menu selected function: trajectoryMenu
        function trajectoryMenuSelected(app, event)
            app.da.trajectory;
        end

        % Menu selected function: envirvehinfoMenu
        function envirvehinfoMenuSelected(app, event)
            app.da.envirvehinfo;
        end

        % Menu selected function: deleteLabelsMenu
        function deleteLabelsMenuSelected(app, event)
            app.da.deletealltext;
            app.trajectorycontinue = 0;
            app.envvehfromavomain_swich = 0;
            app.envvehfromoncoming_swich = 0;
            app.envpedfromavo_swich = 0;
        end

        % Menu selected function: startwiththefirstnumberMenu
        function startwiththefirstnumberMenuSelected(app, event)
            app.da.dcm.Enable = 'on';
            app.da.replayer(0);
        end

        % Menu selected function: StartwiththecurrentcursorMenu
        function StartwiththecurrentcursorMenuSelected(app, event)
            app.da.dcm.Enable = 'on';
            app.da.replayer(1);
        end

        % Menu selected function: stopreplayMenu
        function stopreplayMenuSelected(app, event)
            app.da.dcm.Enable = 'off';
        end

        % Menu selected function: singleLablesMenu
        function singleLablesMenuSelected(app, event)
            prompt = {'Enter matrix single label:'};
            dlgtitle = 'Single Input';
            dims = [1 40];
            definput = {'targetSpd'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                app.da.opentext(deblank(answer{1,1}))
            end
        end

        % Menu selected function: opencarviewMenu
        function opencarviewMenuSelected(app, event)
            app.viewswich = true ;
        end

        % Menu selected function: closecarviewMenu
        function closecarviewMenuSelected(app, event)
            app.viewswich = false;
        end

        % Menu selected function: scrollMenu
        function scrollMenuSelected(app, event)
%             if 
            scroll(app.UITable,'row',app.da.dataIndex);
%             end
        end

        % Menu selected function: addtoselectdataMenu
        function addtoselectdataMenuSelected(app, event)
            if ~isempty(app.selectingcolumn_table1)
                app.owndatacolumn_table2 = [app.owndatacolumn_table2,app.selectingcolumn_table1'];
                app.owndatacolumn_table2 = unique(app.owndatacolumn_table2,'stable');
                vars = app.da.T.Properties.VariableNames(app.owndatacolumn_table2);
                app.UITable_2.Data = app.da.T(:,vars);
                app.UITable_2.ColumnName = vars;
            end
        end

        % Menu selected function: removefromselectdataMenu
        function removefromselectdataMenuSelected(app, event)
            if ~isempty(app.selectingcolumn_table2)
                app.owndatacolumn_table2 = setdiff(app.owndatacolumn_table2,app.owndatacolumn_table2(app.selectingcolumn_table2));
                if ~isempty(app.owndatacolumn_table2)
                    vars = app.da.T.Properties.VariableNames(app.owndatacolumn_table2);
                    app.UITable_2.Data = app.da.T(:,vars);
                    app.UITable_2.ColumnName = vars;
                else
                    headers = {'Colunm 1' 'Colunm 2' 'Colunm 3 ' 'Colunm 4' };
                    data = cell(4,4);
                    ttable = cell2table(data);
                    ttable.Properties.VariableNames = headers;
                    app.UITable_2.Data = ttable;
                    app.UITable_2.ColumnName = headers;
                end
            end
        end

        % Menu selected function: clearselectdataMenu
        function clearselectdataMenuSelected(app, event)
            headers = {'Colunm 1' 'Colunm 2' 'Colunm 3 ' 'Colunm 4' };
            data = cell(4,4);
            ttable = cell2table(data);
            ttable.Properties.VariableNames = headers;
            app.UITable_2.Data = ttable;
            app.UITable_2.ColumnName = headers;
            app.owndatacolumn_table2 = [];
        end

        % Menu selected function: scrollMenu_2
        function scrollMenu_2Selected(app, event)
            scroll(app.UITable,'row',app.da.dataIndex);
        end

        % Menu selected function: scrollMenu_3
        function scrollMenu_3Selected(app, event)
            scroll(app.UITable_3,'row',app.da.vehData_runIndex);
        end

        % Menu selected function: addtoselectdataMenu_2
        function addtoselectdataMenu_2Selected(app, event)
            if ~isempty(app.selectingcolumn_table1)
                app.owndatacolumn_table2 = [app.owndatacolumn_table2,app.selectingcolumn_table1'];
                app.owndatacolumn_table2 = unique(app.owndatacolumn_table2,'stable');
                vars = app.da.T.Properties.VariableNames(app.owndatacolumn_table2);
                app.UITable_2.Data = app.da.T(:,vars);
                app.UITable_2.ColumnName = vars;
            end
        end

        % Menu selected function: removefromselectdataMenu_2
        function removefromselectdataMenu_2Selected(app, event)
            if ~isempty(app.selectingcolumn_table2)
                app.owndatacolumn_table2 = setdiff(app.owndatacolumn_table2,app.owndatacolumn_table2(app.selectingcolumn_table2));
                if ~isempty(app.owndatacolumn_table2)
                    vars = app.da.T.Properties.VariableNames(app.owndatacolumn_table2);
                    app.UITable_2.Data = app.da.T(:,vars);
                    app.UITable_2.ColumnName = vars;
                else
                    headers = {'Colunm 1' 'Colunm 2' 'Colunm 3 ' 'Colunm 4' };
                    data = cell(4,4);
                    ttable = cell2table(data);
                    ttable.Properties.VariableNames = headers;
                    app.UITable_2.Data = ttable;
                    app.UITable_2.ColumnName = headers;
                end
            end
        end

        % Menu selected function: clearselectdataMenu_2
        function clearselectdataMenu_2Selected(app, event)
            headers = {'Colunm 1' 'Colunm 2' 'Colunm 3 ' 'Colunm 4' };
            data = cell(4,4);
            ttable = cell2table(data);
            ttable.Properties.VariableNames = headers;
            app.UITable_2.Data = ttable;
            app.UITable_2.ColumnName = headers;
            app.owndatacolumn_table2 = [];
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'eTown')
                app.da.mapchange(0);
                app.TextAreaLine.Value = "Map of Yizhuang";
                app.TabGroup3.SelectedTab = app.TabGroup3.Children(2);
            elseif strcmp(selectedButton.Text,'chongqing')
                app.da.mapchange(1)
                app.TextAreaLine.Value = "Map of Chongqing";
                app.TabGroup3.SelectedTab = app.TabGroup3.Children(3);
            end
        end

        % Button pushed function: printtrackButton
        function printtrackButtonPushed(app, event)
            app.da.trajectory;
            app.trajectorycontinue = 1;
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if ~isempty(event.Modifier)
                key = event.Key;
                Modifier = event.Modifier{1,1};
                if strcmp(Modifier,'control') && strcmp(key,'pagedown')
                    numOfTab = length(app.TabGroup2.Children);
                    for tabIndex=1:numOfTab
                        if strcmp(app.TabGroup2.Children(tabIndex).Title,app.TabGroup2.SelectedTab.Title)
                            break
                        end
                    end
                    if tabIndex+1 <= numOfTab
                        app.TabGroup2.SelectedTab = app.TabGroup2.Children(tabIndex+1);
                    end
                elseif strcmp(Modifier,'control') && strcmp(key,'pageup')
                    numOfTab = length(app.TabGroup2.Children);
                    for tabIndex=1:numOfTab
                        if strcmp(app.TabGroup2.Children(tabIndex).Title,app.TabGroup2.SelectedTab.Title)
                            break
                        end
                    end
                    if tabIndex-1 >= 1
                        app.TabGroup2.SelectedTab = app.TabGroup2.Children(tabIndex-1);
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close
            delete(app)
        end

        % Callback function: Tree2
        function Tree2CheckedNodesChanged(app, event)
            checkedNodes = app.Tree2.CheckedNodes;
            if ~isempty(app.da_gx_h)
                h_fieldnames = fieldnames(app.da_gx_h);
                if sum(strcmp('laneline',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.laneline)
                end
                if sum(strcmp('lanes_new',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.lanes_new)
                end
                if sum(strcmp('lane_nodepoint',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.lane_nodepoint)
                end
                if sum(strcmp('lane_markingline',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.lane_markingline)
                end

                if sum(strcmp('stop_line',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.stop_line)
                end
                if sum(strcmp('crossinfo',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.crossinfo)
                    delete(app.da_gx_h.text_crossinfo)
                end
                if sum(strcmp('intersection_polygon',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.intersection_polygon)
                end
                if sum(strcmp('crosswalk',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.crosswalk)
                end

                if sum(strcmp('linkline',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.linkline)
                end
                if sum(strcmp('link_nodepoint',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.link_nodepoint)
                end
                if sum(strcmp('boundary',h_fieldnames)) ~= 0
                    delete(app.da_gx_h.boundary)
                end
            end
            for i=1:length(checkedNodes)
                if strcmp(checkedNodes(i).Text,'yzsfq_hd_laneline_v22_84')
                    app.da_gx_h.laneline =geoplot(app.da.GeoTable.laneline_table,Color="#D95319");
                elseif strcmp(checkedNodes(i).Text,'lanes')
                    app.da_gx_h.lanes_new =geoplot(app.da.GeoTable.lanes_new_table,Color=[75/255 0 130/255]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_lane_nodepoint_v22_84')
                    app.da_gx_h.lane_nodepoint = geoplot(app.da.GeoTable.lane_nodepoint_table,MarkerSize=20);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_lane_markingline_v22_84')
                    app.da_gx_h.lane_markingline =geoplot(app.da.GeoTable.lane_markingline_table,Color=[1,0.8,0.2]);

                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_stop_line_v22_84')
                    app.da_gx_h.stop_line =geoplot(app.da.GeoTable.stop_line_table,Color=[1,0,0]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_crossinfo_v22_84')
                    app.da_gx_h.crossinfo = geoplot(app.da.GeoTable.crossinfo_table,MarkerSize=20);
                    app.da_gx_h.text_crossinfo = text(app.da_gx_h.crossinfo.ShapeData.Latitude, app.da_gx_h.crossinfo.ShapeData.Longitude,cellstr(app.da.GeoTable.crossinfo_table.cross_code)');
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_intersection_polygon_v22_84')
                    app.da_gx_h.intersection_polygon = geoplot(app.da.GeoTable.intersection_polygon_table,FaceColor=[0.8500 0.3250 0.0980]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_crosswalk_v22_84')
                    app.da_gx_h.crosswalk = geoplot(app.da.GeoTable.crosswalk_table,FaceColor=[0.6350 0.0780 0.1840]);

                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_linkline_v22_84')
                    app.da_gx_h.linkline = geoplot(app.da.GeoTable.linkline_table,Color=[0.8500 0.3250 0.0980]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_link_nodepoint_v22_84')
                    app.da_gx_h.link_nodepoint = geoplot(app.da.GeoTable.link_nodepoint_table,MarkerSize=20);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_boundary_v22_84')
                    app.da_gx_h.boundary = geoplot(app.da.GeoTable.boundary_table,Color=[1,0.5,0.2]);
                end
            end

        end

        % Callback function: Tree2_2
        function Tree2_2CheckedNodesChanged(app, event)
            checkedNodes = app.Tree2_2.CheckedNodes;
            if ~isempty(app.gx_h)
                h_fieldnames = fieldnames(app.gx_h);
                if sum(strcmp('laneline',h_fieldnames)) ~= 0
                    delete(app.gx_h.laneline)
                end
                if sum(strcmp('lane_nodepoint',h_fieldnames)) ~= 0
                    delete(app.gx_h.lane_nodepoint)
                end
                if sum(strcmp('lane_markingline',h_fieldnames)) ~= 0
                    delete(app.gx_h.lane_markingline)
                end

                if sum(strcmp('stop_line',h_fieldnames)) ~= 0
                    delete(app.gx_h.stop_line)
                end
                if sum(strcmp('crossinfo',h_fieldnames)) ~= 0
                    delete(app.gx_h.crossinfo)
                    delete(app.gx_h.text_crossinfo)
                end
                if sum(strcmp('intersection_polygon',h_fieldnames)) ~= 0
                    delete(app.gx_h.intersection_polygon)
                end
                if sum(strcmp('crosswalk',h_fieldnames)) ~= 0
                    delete(app.gx_h.crosswalk)
                end

                if sum(strcmp('linkline',h_fieldnames)) ~= 0
                    delete(app.gx_h.linkline)
                end
                if sum(strcmp('link_nodepoint',h_fieldnames)) ~= 0
                    delete(app.gx_h.link_nodepoint)
                end
                if sum(strcmp('boundary',h_fieldnames)) ~= 0
                    delete(app.gx_h.boundary)
                end
            end
            hold(app.gx,"on");
            for i=1:length(checkedNodes)
                if strcmp(checkedNodes(i).Text,'yzsfq_hd_laneline_v22_84')
                    app.gx_h.laneline =geoplot(app.gx,app.da.GeoTable.laneline_table,Color=[0 0.4470 0.7410]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_lane_nodepoint_v22_84')
                    app.gx_h.lane_nodepoint = geoplot(app.gx,app.da.GeoTable.lane_nodepoint_table,MarkerSize=20);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_lane_markingline_v22_84')
                    app.gx_h.lane_markingline =geoplot(app.gx,app.da.GeoTable.lane_markingline_table,Color=[1,0.8,0.2]);

                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_stop_line_v22_84')
                    app.gx_h.stop_line =geoplot(app.gx,app.da.GeoTable.stop_line_table,Color=[1,0,0]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_crossinfo_v22_84')
                    app.gx_h.crossinfo = geoplot(app.gx,app.da.GeoTable.crossinfo_table,MarkerSize=20);
                    app.gx_h.text_crossinfo = text(app.gx,app.gx_h.crossinfo.ShapeData.Latitude, app.gx_h.crossinfo.ShapeData.Longitude,cellstr(app.da.GeoTable.crossinfo_table.cross_code)');
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_intersection_polygon_v22_84')
                    app.gx_h.intersection_polygon = geoplot(app.gx,app.da.GeoTable.intersection_polygon_table,FaceColor=[0.8500 0.3250 0.0980]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_crosswalk_v22_84')
                    app.gx_h.crosswalk = geoplot(app.gx,app.da.GeoTable.crosswalk_table,FaceColor=[0.6350 0.0780 0.1840]);

                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_linkline_v22_84')
                    app.gx_h.linkline = geoplot(app.gx,app.da.GeoTable.linkline_table,Color=[0.8500 0.3250 0.0980]);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_link_nodepoint_v22_84')
                    app.gx_h.link_nodepoint = geoplot(app.gx,app.da.GeoTable.link_nodepoint_table,MarkerSize=20);
                elseif strcmp(checkedNodes(i).Text,'yzsfq_hd_boundary_v22_84')
                    app.gx_h.boundary = geoplot(app.gx,app.da.GeoTable.boundary_table,Color=[1,0.5,0.2]);
                end
            end
            hold(app.gx,"off");

        end

        % Menu selected function: streetslightMenu
        function streetslightMenuSelected(app, event)
            geobasemap(app.gx,"streets-light")
        end

        % Menu selected function: streetsdarkMenu
        function streetsdarkMenuSelected(app, event)
            geobasemap(app.gx,"streets-dark")
        end

        % Menu selected function: streetsMenu
        function streetsMenuSelected(app, event)
            geobasemap(app.gx,"streets")
        end

        % Menu selected function: satelliteMenu
        function satelliteMenuSelected(app, event)
            geobasemap(app.gx,"satellite")
        end

        % Menu selected function: topographicMenu
        function topographicMenuSelected(app, event)
            geobasemap(app.gx,"topographic")
        end

        % Menu selected function: landcoverMenu
        function landcoverMenuSelected(app, event)
            geobasemap(app.gx,"landcover")
        end

        % Menu selected function: colorterrainMenu
        function colorterrainMenuSelected(app, event)
            geobasemap(app.gx,"colorterrain")
        end

        % Menu selected function: grayterrainMenu
        function grayterrainMenuSelected(app, event)
            geobasemap(app.gx,"grayterrain")
        end

        % Menu selected function: bluegreenMenu
        function bluegreenMenuSelected(app, event)
            geobasemap(app.gx,"bluegreen")
        end

        % Menu selected function: graylandMenu
        function graylandMenuSelected(app, event)
            geobasemap(app.gx,"grayland")
        end

        % Menu selected function: darkwaterMenu
        function darkwaterMenuSelected(app, event)
            geobasemap(app.gx,"darkwater")
        end

        % Button pushed function: avomainro_envvehinfoButton
        function avomainro_envvehinfoButtonPushed(app, event)
            app.da.envvehfromavomain;
            app.envvehfromavomain_swich = 1;
        end

        % Button pushed function: oncoming_envvehinfoButton
        function oncoming_envvehinfoButtonPushed(app, event)
            app.da.envvehfromoncoming;
            app.envvehfromoncoming_swich = 1;
        end

        % Menu selected function: noneMenu
        function noneMenuSelected(app, event)
            geobasemap(app.gx,"none")
        end

        % Button pushed function: envirpedinfoButton
        function envirpedinfoButtonPushed(app, event)
            app.da.envpedfromavo;
            app.envpedfromavo_swich = 1;
        end

        % Menu selected function: printtrackMenu
        function printtrackMenuSelected(app, event)
            app.da.trajectory;
            app.trajectorycontinue = 1;
        end

        % Menu selected function: avomainro_envvehinfoMenu
        function avomainro_envvehinfoMenuSelected(app, event)
            app.da.trajectory;
            app.trajectorycontinue = 1;
        end

        % Menu selected function: oncoming_envvehinfoMenu
        function oncoming_envvehinfoMenuSelected(app, event)
            app.da.envvehfromoncoming;
            app.envvehfromoncoming_swich = 1;
        end

        % Menu selected function: envirpedinfoMenu
        function envirpedinfoMenuSelected(app, event)
            app.da.envpedfromavo;
            app.envpedfromavo_swich = 1;
        end

        % Button pushed function: envobjectfromvehButton
        function envobjectfromvehButtonPushed(app, event)
            app.da.envobjectfromveh;
            app.indexTextArea.Value = string(app.da.vehData_runIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.fileMenu = uimenu(app.UIFigure);
            app.fileMenu.Text = '   &file  ';
            app.ImportfileIMenu = uimenu(app.fileMenu);
            app.ImportfileIMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfileIMenuSelected, true);
            app.ImportfileIMenu.Text = '&Import file (I)';
            app.mapMenu = uimenu(app.UIFigure);
            app.mapMenu.Text = ' &map ';
            app.YizhuangMapMenu = uimenu(app.mapMenu);
            app.YizhuangMapMenu.MenuSelectedFcn = createCallbackFcn(app, @YizhuangMapMenuSelected, true);
            app.YizhuangMapMenu.Text = '&YizhuangMap';
            app.ChongqingMapMenu = uimenu(app.mapMenu);
            app.ChongqingMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ChongqingMapMenuSelected, true);
            app.ChongqingMapMenu.Text = '&ChongqingMap';
            app.SelectLaneRangeMenu = uimenu(app.mapMenu);
            app.SelectLaneRangeMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectLaneRangeMenuSelected, true);
            app.SelectLaneRangeMenu.Text = '&SelectLaneRange';
            app.ClearMapMenu = uimenu(app.mapMenu);
            app.ClearMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMapMenuSelected, true);
            app.ClearMapMenu.Text = '&ClearMap';
            app.measureMenu = uimenu(app.UIFigure);
            app.measureMenu.Text = ' measur&e ';
            app.measurelineMenu = uimenu(app.measureMenu);
            app.measurelineMenu.MenuSelectedFcn = createCallbackFcn(app, @measurelineMenuSelected, true);
            app.measurelineMenu.Text = '&measure line';
            app.clearmeasureMenu = uimenu(app.measureMenu);
            app.clearmeasureMenu.MenuSelectedFcn = createCallbackFcn(app, @clearmeasureMenuSelected, true);
            app.clearmeasureMenu.Text = '&clear measure';
            app.toolMenu = uimenu(app.UIFigure);
            app.toolMenu.Text = '&tool';
            app.deleteLabelsMenu = uimenu(app.toolMenu);
            app.deleteLabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteLabelsMenuSelected, true);
            app.deleteLabelsMenu.Text = '&delete Labels';
            app.singleLablesMenu = uimenu(app.toolMenu);
            app.singleLablesMenu.MenuSelectedFcn = createCallbackFcn(app, @singleLablesMenuSelected, true);
            app.singleLablesMenu.Text = '&single Lables';
            app.linksetMenu = uimenu(app.toolMenu);
            app.linksetMenu.MenuSelectedFcn = createCallbackFcn(app, @linksetMenuSelected, true);
            app.linksetMenu.Text = 'links&et';
            app.linklistMenu = uimenu(app.toolMenu);
            app.linklistMenu.MenuSelectedFcn = createCallbackFcn(app, @linklistMenuSelected, true);
            app.linklistMenu.Text = '&linklist';
            app.lanelistMenu = uimenu(app.toolMenu);
            app.lanelistMenu.MenuSelectedFcn = createCallbackFcn(app, @lanelistMenuSelected, true);
            app.lanelistMenu.Text = 'l&anelist';
            app.curpathMenu = uimenu(app.toolMenu);
            app.curpathMenu.MenuSelectedFcn = createCallbackFcn(app, @curpathMenuSelected, true);
            app.curpathMenu.Text = '&curpath';
            app.tagtpathMenu = uimenu(app.toolMenu);
            app.tagtpathMenu.MenuSelectedFcn = createCallbackFcn(app, @tagtpathMenuSelected, true);
            app.tagtpathMenu.Text = '&tagtpath';
            app.trajectoryMenu = uimenu(app.toolMenu);
            app.trajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @trajectoryMenuSelected, true);
            app.trajectoryMenu.Text = 't&rajectory';
            app.envirvehinfoMenu = uimenu(app.toolMenu);
            app.envirvehinfoMenu.MenuSelectedFcn = createCallbackFcn(app, @envirvehinfoMenuSelected, true);
            app.envirvehinfoMenu.Text = 'envirvehinf&o';
            app.printtrackMenu = uimenu(app.toolMenu);
            app.printtrackMenu.MenuSelectedFcn = createCallbackFcn(app, @printtrackMenuSelected, true);
            app.printtrackMenu.Text = 'print track';
            app.avomainro_envvehinfoMenu = uimenu(app.toolMenu);
            app.avomainro_envvehinfoMenu.MenuSelectedFcn = createCallbackFcn(app, @avomainro_envvehinfoMenuSelected, true);
            app.avomainro_envvehinfoMenu.Text = 'avomainro_envvehinfo';
            app.oncoming_envvehinfoMenu = uimenu(app.toolMenu);
            app.oncoming_envvehinfoMenu.MenuSelectedFcn = createCallbackFcn(app, @oncoming_envvehinfoMenuSelected, true);
            app.oncoming_envvehinfoMenu.Text = 'oncoming_envvehinfo';
            app.envirpedinfoMenu = uimenu(app.toolMenu);
            app.envirpedinfoMenu.MenuSelectedFcn = createCallbackFcn(app, @envirpedinfoMenuSelected, true);
            app.envirpedinfoMenu.Text = 'envirpedinfo';
            app.replayerMenu = uimenu(app.UIFigure);
            app.replayerMenu.Text = ' &replayer ';
            app.startwiththefirstnumberMenu = uimenu(app.replayerMenu);
            app.startwiththefirstnumberMenu.MenuSelectedFcn = createCallbackFcn(app, @startwiththefirstnumberMenuSelected, true);
            app.startwiththefirstnumberMenu.Text = 'start with the &first number';
            app.StartwiththecurrentcursorMenu = uimenu(app.replayerMenu);
            app.StartwiththecurrentcursorMenu.MenuSelectedFcn = createCallbackFcn(app, @StartwiththecurrentcursorMenuSelected, true);
            app.StartwiththecurrentcursorMenu.Text = 'Start with the current &cursor';
            app.stopreplayMenu = uimenu(app.replayerMenu);
            app.stopreplayMenu.MenuSelectedFcn = createCallbackFcn(app, @stopreplayMenuSelected, true);
            app.stopreplayMenu.Text = 'st&op replay';
            app.viewMenu = uimenu(app.UIFigure);
            app.viewMenu.Text = ' &view ';
            app.opencarviewMenu = uimenu(app.viewMenu);
            app.opencarviewMenu.MenuSelectedFcn = createCallbackFcn(app, @opencarviewMenuSelected, true);
            app.opencarviewMenu.Text = '&open car view';
            app.closecarviewMenu = uimenu(app.viewMenu);
            app.closecarviewMenu.MenuSelectedFcn = createCallbackFcn(app, @closecarviewMenuSelected, true);
            app.closecarviewMenu.Text = '&close car view';
            app.basemapMenu = uimenu(app.viewMenu);
            app.basemapMenu.Text = 'basemap';
            app.streetslightMenu = uimenu(app.basemapMenu);
            app.streetslightMenu.MenuSelectedFcn = createCallbackFcn(app, @streetslightMenuSelected, true);
            app.streetslightMenu.Text = 'streets-light';
            app.streetsdarkMenu = uimenu(app.basemapMenu);
            app.streetsdarkMenu.MenuSelectedFcn = createCallbackFcn(app, @streetsdarkMenuSelected, true);
            app.streetsdarkMenu.Text = 'streets-dark';
            app.streetsMenu = uimenu(app.basemapMenu);
            app.streetsMenu.MenuSelectedFcn = createCallbackFcn(app, @streetsMenuSelected, true);
            app.streetsMenu.Text = 'streets';
            app.satelliteMenu = uimenu(app.basemapMenu);
            app.satelliteMenu.MenuSelectedFcn = createCallbackFcn(app, @satelliteMenuSelected, true);
            app.satelliteMenu.Text = 'satellite';
            app.topographicMenu = uimenu(app.basemapMenu);
            app.topographicMenu.MenuSelectedFcn = createCallbackFcn(app, @topographicMenuSelected, true);
            app.topographicMenu.Text = 'topographic';
            app.landcoverMenu = uimenu(app.basemapMenu);
            app.landcoverMenu.MenuSelectedFcn = createCallbackFcn(app, @landcoverMenuSelected, true);
            app.landcoverMenu.Text = 'landcover';
            app.colorterrainMenu = uimenu(app.basemapMenu);
            app.colorterrainMenu.MenuSelectedFcn = createCallbackFcn(app, @colorterrainMenuSelected, true);
            app.colorterrainMenu.Text = 'colorterrain';
            app.grayterrainMenu = uimenu(app.basemapMenu);
            app.grayterrainMenu.MenuSelectedFcn = createCallbackFcn(app, @grayterrainMenuSelected, true);
            app.grayterrainMenu.Text = 'grayterrain';
            app.bluegreenMenu = uimenu(app.basemapMenu);
            app.bluegreenMenu.MenuSelectedFcn = createCallbackFcn(app, @bluegreenMenuSelected, true);
            app.bluegreenMenu.Text = 'bluegreen';
            app.graylandMenu = uimenu(app.basemapMenu);
            app.graylandMenu.MenuSelectedFcn = createCallbackFcn(app, @graylandMenuSelected, true);
            app.graylandMenu.Text = 'grayland';
            app.darkwaterMenu = uimenu(app.basemapMenu);
            app.darkwaterMenu.MenuSelectedFcn = createCallbackFcn(app, @darkwaterMenuSelected, true);
            app.darkwaterMenu.Text = 'darkwater';
            app.noneMenu = uimenu(app.basemapMenu);
            app.noneMenu.MenuSelectedFcn = createCallbackFcn(app, @noneMenuSelected, true);
            app.noneMenu.Text = 'none';
            app.tableMenu = uimenu(app.UIFigure);
            app.tableMenu.Text = 'ta&ble';
            app.scrollMenu = uimenu(app.tableMenu);
            app.scrollMenu.MenuSelectedFcn = createCallbackFcn(app, @scrollMenuSelected, true);
            app.scrollMenu.Text = '&scroll';
            app.addtoselectdataMenu = uimenu(app.tableMenu);
            app.addtoselectdataMenu.MenuSelectedFcn = createCallbackFcn(app, @addtoselectdataMenuSelected, true);
            app.addtoselectdataMenu.Text = '&add to select data';
            app.removefromselectdataMenu = uimenu(app.tableMenu);
            app.removefromselectdataMenu.MenuSelectedFcn = createCallbackFcn(app, @removefromselectdataMenuSelected, true);
            app.removefromselectdataMenu.Text = '&remove from select data';
            app.clearselectdataMenu = uimenu(app.tableMenu);
            app.clearselectdataMenu.MenuSelectedFcn = createCallbackFcn(app, @clearselectdataMenuSelected, true);
            app.clearselectdataMenu.Text = '&clear select data';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.analysisTab = uitab(app.TabGroup2);
            app.analysisTab.Title = 'analysis';
            app.UIAxes = uiaxes(app.analysisTab);
            title(app.UIAxes, 'linklist')
            xlabel(app.UIAxes, 's')
            zlabel(app.UIAxes, 'Z')
            app.pathLabel = uilabel(app.analysisTab);
            app.pathLabel.Text = 'path';
            app.DataPanel = uipanel(app.analysisTab);
            app.DataPanel.Title = 'Data';
            app.TextAreaLeft1 = uitextarea(app.DataPanel);
            app.TextAreaCipv = uitextarea(app.DataPanel);
            app.TextAreaRight1 = uitextarea(app.DataPanel);
            app.TextArealeft2 = uitextarea(app.DataPanel);
            app.TextAreaVeh = uitextarea(app.DataPanel);
            app.TextAreaRight2 = uitextarea(app.DataPanel);
            app.TextAreaScene = uitextarea(app.DataPanel);
            app.CIPVLabel = uilabel(app.DataPanel);
            app.CIPVLabel.Text = 'CIPV';
            app.LeftFrontLabel = uilabel(app.DataPanel);
            app.LeftFrontLabel.Text = 'LeftFront';
            app.RightBehindLabel = uilabel(app.DataPanel);
            app.RightBehindLabel.Text = 'RightBehind';
            app.LeftBehindLabel = uilabel(app.DataPanel);
            app.LeftBehindLabel.Text = 'LeftBehind';
            app.RightFrontLabel = uilabel(app.DataPanel);
            app.RightFrontLabel.Text = 'RightFront';
            app.VehicleLabel = uilabel(app.DataPanel);
            app.VehicleLabel.Text = 'Vehicle';
            app.SceneactivationLabel = uilabel(app.DataPanel);
            app.SceneactivationLabel.Text = 'Scene activation';
            app.TextAreaDecision = uitextarea(app.DataPanel);
            app.DecisionLabel = uilabel(app.DataPanel);
            app.DecisionLabel.Text = 'Decision';
            app.TextAreaStates = uitextarea(app.DataPanel);
            app.StatesLabel = uilabel(app.DataPanel);
            app.StatesLabel.Text = 'States';
            app.TextAreaLine = uitextarea(app.DataPanel);
            app.TextAreaLight = uitextarea(app.DataPanel);
            app.TextAreaRoute = uitextarea(app.DataPanel);
            app.LightLabel = uilabel(app.DataPanel);
            app.LightLabel.Text = 'Light';
            app.RouteLabel = uilabel(app.DataPanel);
            app.RouteLabel.Text = 'Route';
            app.TextAreaFilePath = uitextarea(app.analysisTab);
            app.TabGroup = uitabgroup(app.analysisTab);
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.ToolTab = uitab(app.TabGroup);
            app.ToolTab.Title = 'Tool';
            app.linksetButton = uibutton(app.ToolTab, 'push');
            app.linksetButton.ButtonPushedFcn = createCallbackFcn(app, @linksetButtonPushed, true);
            app.linksetButton.Text = 'linkset';
            app.linklistButton = uibutton(app.ToolTab, 'push');
            app.linklistButton.ButtonPushedFcn = createCallbackFcn(app, @linklistButtonPushed, true);
            app.linklistButton.Text = 'linklist';
            app.lanelistButton = uibutton(app.ToolTab, 'push');
            app.lanelistButton.ButtonPushedFcn = createCallbackFcn(app, @lanelistButtonPushed, true);
            app.lanelistButton.Text = 'lanelist';
            app.TextButton = uibutton(app.ToolTab, 'push');
            app.TextButton.ButtonPushedFcn = createCallbackFcn(app, @TextButtonPushed, true);
            app.TextButton.Text = 'Text';
            app.clearallprintButton = uibutton(app.ToolTab, 'push');
            app.clearallprintButton.ButtonPushedFcn = createCallbackFcn(app, @clearallprintButtonPushed, true);
            app.clearallprintButton.Text = 'clear all print';
            app.curpathButton = uibutton(app.ToolTab, 'push');
            app.curpathButton.ButtonPushedFcn = createCallbackFcn(app, @curpathButtonPushed, true);
            app.curpathButton.Text = 'curpath';
            app.tagtpathButton = uibutton(app.ToolTab, 'push');
            app.tagtpathButton.ButtonPushedFcn = createCallbackFcn(app, @tagtpathButtonPushed, true);
            app.tagtpathButton.Text = 'tagtpath';
            app.trajectoryButton = uibutton(app.ToolTab, 'push');
            app.trajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @trajectoryButtonPushed, true);
            app.trajectoryButton.Text = 'trajectory';
            app.envirvehinfoButton = uibutton(app.ToolTab, 'push');
            app.envirvehinfoButton.ButtonPushedFcn = createCallbackFcn(app, @envirvehinfoButtonPushed, true);
            app.envirvehinfoButton.Text = 'envirvehinfo';
            app.DropDown = uidropdown(app.ToolTab);
            app.DropDown.Editable = 'on';
            app.printtrackButton = uibutton(app.ToolTab, 'push');
            app.printtrackButton.ButtonPushedFcn = createCallbackFcn(app, @printtrackButtonPushed, true);
            app.printtrackButton.Text = 'print track';
            app.avomainro_envvehinfoButton = uibutton(app.ToolTab, 'push');
            app.avomainro_envvehinfoButton.ButtonPushedFcn = createCallbackFcn(app, @avomainro_envvehinfoButtonPushed, true);
            app.avomainro_envvehinfoButton.Text = 'avomainro_envvehinfo';
            app.oncoming_envvehinfoButton = uibutton(app.ToolTab, 'push');
            app.oncoming_envvehinfoButton.ButtonPushedFcn = createCallbackFcn(app, @oncoming_envvehinfoButtonPushed, true);
            app.oncoming_envvehinfoButton.Text = 'oncoming_envvehinfo';
            app.envirpedinfoButton = uibutton(app.ToolTab, 'push');
            app.envirpedinfoButton.ButtonPushedFcn = createCallbackFcn(app, @envirpedinfoButtonPushed, true);
            app.envirpedinfoButton.Text = 'envirpedinfo';
            app.envobjectfromvehButton = uibutton(app.ToolTab, 'push');
            app.envobjectfromvehButton.ButtonPushedFcn = createCallbackFcn(app, @envobjectfromvehButtonPushed, true);
            app.envobjectfromvehButton.Text = 'envobjectfromveh';
            app.FileDropTab = uitab(app.TabGroup);
            app.FileDropTab.Title = 'FileDrop';
            app.ListBox = uilistbox(app.FileDropTab);
            app.ListBox.Items = {'filePath'};
            app.ListBox.Value = 'filePath';
            app.DropsomefilesortextcontenthereLabel = uilabel(app.FileDropTab);
            app.DropsomefilesortextcontenthereLabel.Text = 'Drop some files or text content here.';
            app.LoadButton = uibutton(app.FileDropTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TabGroup3 = uitabgroup(app.analysisTab);
            app.eTownTab = uitab(app.TabGroup3);
            app.eTownTab.Title = 'eTown';
            app.Tree = uitree(app.eTownTab, 'checkbox');
            app.lanelinepartNode = uitreenode(app.Tree);
            app.lanelinepartNode.Text = 'lanelinepart';
            app.linklinepartNode = uitreenode(app.Tree);
            app.linklinepartNode.Text = 'linklinepart';
            app.lanelineNode = uitreenode(app.Tree);
            app.lanelineNode.Text = 'laneline';
            app.linklineNode = uitreenode(app.Tree);
            app.linklineNode.Text = 'linkline';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.eTownAllTab = uitab(app.TabGroup3);
            app.eTownAllTab.Title = 'eTownAll';
            app.Tree2 = uitree(app.eTownAllTab, 'checkbox');
            app.yzsfq_hd_laneline_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_laneline_v22_84Node.Text = 'yzsfq_hd_laneline_v22_84';
            app.lanesNode = uitreenode(app.Tree2);
            app.lanesNode.Text = 'lanes';
            app.yzsfq_hd_linkline_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_linkline_v22_84Node.Text = 'yzsfq_hd_linkline_v22_84';
            app.yzsfq_hd_lane_nodepoint_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_lane_nodepoint_v22_84Node.Text = 'yzsfq_hd_lane_nodepoint_v22_84';
            app.yzsfq_hd_link_nodepoint_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_link_nodepoint_v22_84Node.Text = 'yzsfq_hd_link_nodepoint_v22_84';
            app.yzsfq_hd_stop_line_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_stop_line_v22_84Node.Text = 'yzsfq_hd_stop_line_v22_84';
            app.yzsfq_hd_crossinfo_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_crossinfo_v22_84Node.Text = 'yzsfq_hd_crossinfo_v22_84';
            app.yzsfq_hd_intersection_polygon_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_intersection_polygon_v22_84Node.Text = 'yzsfq_hd_intersection_polygon_v22_84';
            app.yzsfq_hd_crosswalk_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_crosswalk_v22_84Node.Text = 'yzsfq_hd_crosswalk_v22_84';
            app.yzsfq_hd_lane_markingline_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_lane_markingline_v22_84Node.Text = 'yzsfq_hd_lane_markingline_v22_84';
            app.yzsfq_hd_boundary_v22_84Node = uitreenode(app.Tree2);
            app.yzsfq_hd_boundary_v22_84Node.Text = 'yzsfq_hd_boundary_v22_84';
            % Assign Checked Nodes
            app.Tree2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree2CheckedNodesChanged, true);
            app.QingTab = uitab(app.TabGroup3);
            app.QingTab.Title = 'Qing';
            app.Tree2_2 = uitree(app.QingTab, 'checkbox');
            app.yzsfq_hd_laneline_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_laneline_v22_84Node_2.Text = 'yzsfq_hd_laneline_v22_84';
            app.yzsfq_hd_linkline_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_linkline_v22_84Node_2.Text = 'yzsfq_hd_linkline_v22_84';
            app.yzsfq_hd_lane_nodepoint_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_lane_nodepoint_v22_84Node_2.Text = 'yzsfq_hd_lane_nodepoint_v22_84';
            app.yzsfq_hd_link_nodepoint_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_link_nodepoint_v22_84Node_2.Text = 'yzsfq_hd_link_nodepoint_v22_84';
            app.yzsfq_hd_stop_line_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_stop_line_v22_84Node_2.Text = 'yzsfq_hd_stop_line_v22_84';
            app.yzsfq_hd_crossinfo_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_crossinfo_v22_84Node_2.Text = 'yzsfq_hd_crossinfo_v22_84';
            app.yzsfq_hd_intersection_polygon_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_intersection_polygon_v22_84Node_2.Text = 'yzsfq_hd_intersection_polygon_v22_84';
            app.yzsfq_hd_crosswalk_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_crosswalk_v22_84Node_2.Text = 'yzsfq_hd_crosswalk_v22_84';
            app.yzsfq_hd_lane_markingline_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_lane_markingline_v22_84Node_2.Text = 'yzsfq_hd_lane_markingline_v22_84';
            app.yzsfq_hd_boundary_v22_84Node_2 = uitreenode(app.Tree2_2);
            app.yzsfq_hd_boundary_v22_84Node_2.Text = 'yzsfq_hd_boundary_v22_84';
            % Assign Checked Nodes
            app.Tree2_2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree2_2CheckedNodesChanged, true);
            app.ButtonGroup = uibuttongroup(app.analysisTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.eTownButton = uiradiobutton(app.ButtonGroup);
            app.eTownButton.Text = 'eTown';
            app.eTownButton.Value = true;
            app.chongqingButton = uiradiobutton(app.ButtonGroup);
            app.chongqingButton.Text = 'chongqing';
            app.MapLabel = uilabel(app.ButtonGroup);
            app.MapLabel.Text = 'Map';
            app.rawdataTab = uitab(app.TabGroup2);
            app.rawdataTab.Title = 'raw data';
            app.UITable = uitable(app.rawdataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'};
            app.UITable.RowName = {};
            app.Textvariablename = uitextarea(app.rawdataTab);
            app.TextAreadata = uitextarea(app.rawdataTab);
            app.DropDown_2 = uidropdown(app.rawdataTab);
            app.DropDown_2.Editable = 'on';
            app.FindButton = uibutton(app.rawdataTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.selectdataTab = uitab(app.TabGroup2);
            app.selectdataTab.Title = 'select data';
            app.UITable_2 = uitable(app.selectdataTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.Textvariablename_2 = uitextarea(app.selectdataTab);
            app.TextAreadata_2 = uitextarea(app.selectdataTab);
            app.rundataTab = uitab(app.TabGroup2);
            app.rundataTab.Title = 'run data';
            app.UITable_3 = uitable(app.rundataTab);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.Textvariablename_3 = uitextarea(app.rundataTab);
            app.TextAreadata_3 = uitextarea(app.rundataTab);
            app.indexTextArea = uitextarea(app.rundataTab);
            app.indexLabel = uilabel(app.rundataTab);
            app.indexLabel.Text = 'index';
            app.UITable_4 = uitable(app.rundataTab);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'; 'Column 4'};
            app.UITable_4.RowName = {};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.scrollMenu_2 = uimenu(app.ContextMenu);
            app.scrollMenu_2.MenuSelectedFcn = createCallbackFcn(app, @scrollMenu_2Selected, true);
            app.scrollMenu_2.Text = 'scroll';
            app.addtoselectdataMenu_2 = uimenu(app.ContextMenu);
            app.addtoselectdataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @addtoselectdataMenu_2Selected, true);
            app.addtoselectdataMenu_2.Text = 'add to select data';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.removefromselectdataMenu_2 = uimenu(app.ContextMenu2);
            app.removefromselectdataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @removefromselectdataMenu_2Selected, true);
            app.removefromselectdataMenu_2.Text = 'remove from select data';
            app.clearselectdataMenu_2 = uimenu(app.ContextMenu2);
            app.clearselectdataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @clearselectdataMenu_2Selected, true);
            app.clearselectdataMenu_2.Text = 'clear select data';
            % Assign app.ContextMenu2
            app.UITable_2.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu3);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.Menu);
            app.Menu2_2.Checked = 'on';
            app.Menu2_2.Text = 'Menu2';
            app.Menu3 = uimenu(app.Menu);
            app.Menu3.Text = 'Menu3';
            app.Menu2 = uimenu(app.ContextMenu3);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu3
            app.TextAreaLine.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.scrollMenu_3 = uimenu(app.ContextMenu4);
            app.scrollMenu_3.MenuSelectedFcn = createCallbackFcn(app, @scrollMenu_3Selected, true);
            app.scrollMenu_3.Text = 'scroll';
            % Assign app.ContextMenu4
            app.UITable_3.ContextMenu = app.ContextMenu4;
            app.UITable_4.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end